classdef IMAGING_ANALYSIS_FILTERS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        POLYNOMIALORDEREditField       matlab.ui.control.NumericEditField
        POLYNOMIALORDEREditFieldLabel  matlab.ui.control.Label
        WINDOWSIZEEditField            matlab.ui.control.NumericEditField
        WINDOWSIZEEditFieldLabel       matlab.ui.control.Label
        SAVITZKYGOLAYFILTERPARAMETERSLabel  matlab.ui.control.Label
        SUBMITPEAKSButton              matlab.ui.control.Button
        TEMPORARYMEMORYLabel           matlab.ui.control.Label
        WILLERASEALLDATAFROMLabel      matlab.ui.control.Label
        WILLSTOREDATAASFILESBUTLabel   matlab.ui.control.Label
        WARNINGLabel                   matlab.ui.control.Label
        STEP13Label_2                  matlab.ui.control.Label
        STEP125CONTINUETILLALLTRACESAREDONELabel  matlab.ui.control.Label
        COMPILEDATAButton              matlab.ui.control.Button
        STEP5Label_2                   matlab.ui.control.Label
        STEP4Label                     matlab.ui.control.Label
        STEP2Label                     matlab.ui.control.Label
        DETECTANDADDPEAKSButton        matlab.ui.control.Button
        FITBASELINEButton              matlab.ui.control.Button
        Lamp2                          matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        SEQUENCEYOUWISHTOPROCESSLabel  matlab.ui.control.Label
        ONDESKTOPTOSTORETHEIMAGELabel  matlab.ui.control.Label
        CHOOSEOSSwitch                 matlab.ui.control.ToggleSwitch
        CHOOSEOSSwitchLabel            matlab.ui.control.Label
        CREATEAFOLDERMOVIE_TESTLabel   matlab.ui.control.Label
        NOTELabel                      matlab.ui.control.Label
        STEP0Label                     matlab.ui.control.Label
        CHOOSEFILEButton               matlab.ui.control.Button
        DATAFILELOCATIONLabel          matlab.ui.control.Label
        STEP5Label                     matlab.ui.control.Label
        STEP3Label                     matlab.ui.control.Label
        MINIMUMINTERPEAKDISTANCEEditField  matlab.ui.control.NumericEditField
        MINIMUMINTERPEAKDISTANCEEditFieldLabel  matlab.ui.control.Label
        PEAKTHRESHOLDEditField         matlab.ui.control.NumericEditField
        PEAKTHRESHOLDEditFieldLabel    matlab.ui.control.Label
        NEXTTRACEButton                matlab.ui.control.Button
        PREVIOUSTRACEButton            matlab.ui.control.Button
        PLOTNORMALIZEDTRACESButton     matlab.ui.control.Button
        STEP1Label                     matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        raw_data                % Original maximum intensity projection file.
        folder                % Switching Directories
        test_direc            % Image sequence directory.
        normalized_data
        current_trace
        interpeak_distance_values
        filtered_data
        y_store
        y_norm_store
        ycorr
        yfit
        ycorr_store
        yfit_store
        idx
        ipd
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CHOOSEOSSwitch
        function CHOOSEOSSwitchValueChanged(app, event)
            
            value = app.CHOOSEOSSwitch.Value;
         
            if strcmp(value, 'Windows')
               app.test_direc.Value = 'E:\Movie_Processing\FILTER_TEST';
               app.Lamp.Color = 'g';
               app.Lamp2.Color = 'r';
            else
               app.test_direc.Value = '/Users/sapuruv/Desktop/Matlab_Workspace/FILTER_TEST';
               app.Lamp.Color = 'r';
               app.Lamp2.Color = 'g';
            end
            
        end

        % Button pushed function: CHOOSEFILEButton
        function CHOOSEFILEButtonPushed(app, event)
        cd '/Users/sapuruv/Desktop/Matlab_Workspace'
        
        app.UIFigure.Visible = 'off';                                       %To bring the file selection pop-up forward.
        
        [file, app.folder] = uigetfile('.xlsx');
        
        app.UIFigure.Visible = 'on';
        
        app.raw_data.Value = xlsread(file);
        
            
        end

        % Button pushed function: PLOTNORMALIZEDTRACESButton
        function PLOTNORMALIZEDTRACESButtonPushed2(app, event)
            
          app.normalized_data.Value = [];
            
            for trace = 1:size(app.raw_data.Value,2)
                j = normalize(app.raw_data.Value(:,trace));
                app.normalized_data.Value = [app.normalized_data.Value j];
            end
            
            writematrix(app.normalized_data.Value, 'normalized_data.xlsx');
            
        app.filtered_data.Value = [];
            
            for line = 1:size(app.normalized_data.Value,2)
                k = sgolayfilt(app.normalized_data.Value(:,line), app.POLYNOMIALORDEREditField.Value, app.WINDOWSIZEEditField.Value);
                app.filtered_data.Value = [app.filtered_data.Value k];
            end
            
            writematrix(app.normalized_data.Value, 'normalized_data.xlsx');
            
        
        app.current_trace.Value = 1;   
            
        plot(app.UIAxes, app.normalized_data.Value(:,app.current_trace.Value), 'LineWidth',1 )
        plot(app.UIAxes_2, app.filtered_data.Value(:,app.current_trace.Value), 'LineWidth',2 )
        
        %Empty vectors that get filled later on.
 
        app.interpeak_distance_values.Value = [];
        app.y_store.Value = [];
        app.yfit_store.Value = [];
        app.ycorr_store.Value = [];
   
        end

        % Button pushed function: FITBASELINEButton
        function FITBASELINEButtonPushed(app, event)
           
           cd(app.test_direc.Value) 
           
           [app.ycorr.Value,app.yfit.Value] = bf(app.filtered_data.Value(:,app.current_trace.Value), 10,'linear');
            
           
           figure;
           plot(app.filtered_data.Value(:,app.current_trace.Value), 'LineWidth',2)
           hold on;
           plot(app.ycorr.Value)
           plot(app.yfit.Value)
           hold off;
           
           plot(app.UIAxes_2, app.normalized_data.Value(:,app.current_trace.Value), 'LineWidth',1)
           hold(app.UIAxes_2, 'on' )
           plot(app.UIAxes_2, app.ycorr.Value, 'LineWidth',2)
           hold(app.UIAxes_2, 'off' )
           
          
        end

        % Button pushed function: DETECTANDADDPEAKSButton
        function DETECTANDADDPEAKSButtonPushed(app, event)
           
           [pks,locs] = findpeaks(app.ycorr.Value, 'MinPeakDistance', app.MINIMUMINTERPEAKDISTANCEEditField.Value);

           peak_location = [];
           
           %Add code to get inter-peak distances.
           
           for c = 1:length(pks)
                    
               if pks(c) > app.PEAKTHRESHOLDEditField.Value
            
                    peak_location = [peak_location, locs(c)];
    
               end
           end
           
           distance = diff(peak_location);
           
           app.ipd.Value = [repelem(app.current_trace.Value, length(distance)); distance];


           peak_values = app.ycorr.Value(peak_location);
           
          
           plot(app.UIAxes_3, app.ycorr.Value, 'LineWidth',2)
           hold(app.UIAxes_3, 'on' )
           plot(app.UIAxes_3, app.normalized_data.Value(:,app.current_trace.Value), 'LineWidth',0.5)
           plot(app.UIAxes_3, peak_location, peak_values,'o', 'LineWidth',2)
           hold(app.UIAxes_3, 'off' )


        end

        % Button pushed function: NEXTTRACEButton
        function NEXTTRACEButtonPushed(app, event)
            
            app.current_trace.Value = app.current_trace.Value + 1;
            
            plot(app.UIAxes, app.normalized_data.Value(:, app.current_trace.Value) )
            
            
        end

        % Button pushed function: PREVIOUSTRACEButton
        function PREVIOUSTRACEButtonPushed(app, event)
            
            app.current_trace.Value = app.current_trace.Value - 1;
            
            plot(app.UIAxes, app.normalized_data.Value(:,app.current_trace.Value) )
            
            
        end

        % Button pushed function: SUBMITPEAKSButton
        function SUBMITPEAKSButtonPushed(app, event)
          
           app.interpeak_distance_values.Value = [app.interpeak_distance_values.Value app.ipd.Value];
            
           app.ycorr_store.Value = [app.ycorr_store.Value app.ycorr.Value];
            
           app.yfit_store.Value = [app.yfit_store.Value app.yfit.Value];
          
        end

        % Button pushed function: COMPILEDATAButton
        function COMPILEDATAButtonPushed(app, event)
     
      writematrix(app.interpeak_distance_values.Value, 'interpeak_distance_values.xlsx');      
      writematrix(app.y_store.Value, 'y_store.xlsx');
      writematrix(app.yfit_store.Value, 'yfit_store.xlsx');
      writematrix(app.ycorr_store.Value, 'ycorr_store.xlsx') ;
      
      clear 
      clc
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'NORMALIZED INDIVIDUAL TRACES')
            xlabel(app.UIAxes, 'FRAME NUMBER')
            ylabel(app.UIAxes, 'STANDARD DEVIATION')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'POST FILTER ')
            xlabel(app.UIAxes_2, 'FRAME NUMBER')
            ylabel(app.UIAxes_2, 'SD')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'PEAK THRESHOLDING AFTER BASELINE ADJUSTMENT ')
            xlabel(app.UIAxes_3, 'FRAME NUMBER')
            ylabel(app.UIAxes_3, 'SD')
            app.STEP1Label = uilabel(app.UIFigure);
            app.STEP1Label.Text = 'STEP 1';
            app.PLOTNORMALIZEDTRACESButton = uibutton(app.UIFigure, 'push');
            app.PLOTNORMALIZEDTRACESButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTNORMALIZEDTRACESButtonPushed2, true);
            app.PLOTNORMALIZEDTRACESButton.Text = 'PLOT NORMALIZED TRACES';
            app.PREVIOUSTRACEButton = uibutton(app.UIFigure, 'push');
            app.PREVIOUSTRACEButton.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSTRACEButtonPushed, true);
            app.PREVIOUSTRACEButton.Text = '< PREVIOUS TRACE';
            app.NEXTTRACEButton = uibutton(app.UIFigure, 'push');
            app.NEXTTRACEButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTTRACEButtonPushed, true);
            app.NEXTTRACEButton.Text = 'NEXT TRACE >';
            app.PEAKTHRESHOLDEditFieldLabel = uilabel(app.UIFigure);
            app.PEAKTHRESHOLDEditFieldLabel.Text = 'PEAK THRESHOLD';
            app.PEAKTHRESHOLDEditField = uieditfield(app.UIFigure, 'numeric');
            app.MINIMUMINTERPEAKDISTANCEEditFieldLabel = uilabel(app.UIFigure);
            app.MINIMUMINTERPEAKDISTANCEEditFieldLabel.Text = 'MINIMUM INTERPEAK DISTANCE';
            app.MINIMUMINTERPEAKDISTANCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.STEP3Label = uilabel(app.UIFigure);
            app.STEP3Label.Text = 'STEP 3';
            app.STEP5Label = uilabel(app.UIFigure);
            app.STEP5Label.Text = 'STEP 5';
            app.DATAFILELOCATIONLabel = uilabel(app.UIFigure);
            app.DATAFILELOCATIONLabel.Text = 'DATA FILE LOCATION';
            app.CHOOSEFILEButton = uibutton(app.UIFigure, 'push');
            app.CHOOSEFILEButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEFILEButtonPushed, true);
            app.CHOOSEFILEButton.Text = 'CHOOSE FILE';
            app.STEP0Label = uilabel(app.UIFigure);
            app.STEP0Label.Text = 'STEP 0';
            app.NOTELabel = uilabel(app.UIFigure);
            app.NOTELabel.Text = 'NOTE:';
            app.CREATEAFOLDERMOVIE_TESTLabel = uilabel(app.UIFigure);
            app.CREATEAFOLDERMOVIE_TESTLabel.Text = 'CREATE A FOLDER ''MOVIE_TEST''  ';
            app.CHOOSEOSSwitchLabel = uilabel(app.UIFigure);
            app.CHOOSEOSSwitchLabel.Text = 'CHOOSE OS';
            app.CHOOSEOSSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CHOOSEOSSwitch.Items = {'MacOS', 'Windows'};
            app.CHOOSEOSSwitch.ValueChangedFcn = createCallbackFcn(app, @CHOOSEOSSwitchValueChanged, true);
            app.CHOOSEOSSwitch.Value = 'MacOS';
            app.ONDESKTOPTOSTORETHEIMAGELabel = uilabel(app.UIFigure);
            app.ONDESKTOPTOSTORETHEIMAGELabel.Text = 'ON DESKTOP TO STORE THE IMAGE';
            app.SEQUENCEYOUWISHTOPROCESSLabel = uilabel(app.UIFigure);
            app.SEQUENCEYOUWISHTOPROCESSLabel.Text = 'SEQUENCE YOU WISH TO PROCESS.';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.FITBASELINEButton = uibutton(app.UIFigure, 'push');
            app.FITBASELINEButton.ButtonPushedFcn = createCallbackFcn(app, @FITBASELINEButtonPushed, true);
            app.FITBASELINEButton.Text = 'FIT BASELINE';
            app.DETECTANDADDPEAKSButton = uibutton(app.UIFigure, 'push');
            app.DETECTANDADDPEAKSButton.ButtonPushedFcn = createCallbackFcn(app, @DETECTANDADDPEAKSButtonPushed, true);
            app.DETECTANDADDPEAKSButton.Text = 'DETECT AND ADD PEAKS';
            app.STEP2Label = uilabel(app.UIFigure);
            app.STEP2Label.Text = 'STEP 2';
            app.STEP4Label = uilabel(app.UIFigure);
            app.STEP4Label.Text = 'STEP 4';
            app.STEP5Label_2 = uilabel(app.UIFigure);
            app.STEP5Label_2.Text = 'STEP 5';
            app.COMPILEDATAButton = uibutton(app.UIFigure, 'push');
            app.COMPILEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @COMPILEDATAButtonPushed, true);
            app.COMPILEDATAButton.Text = 'COMPILE DATA';
            app.STEP125CONTINUETILLALLTRACESAREDONELabel = uilabel(app.UIFigure);
            app.STEP125CONTINUETILLALLTRACESAREDONELabel.Text = 'STEP 12.5. CONTINUE TILL ALL TRACES ARE DONE.';
            app.STEP13Label_2 = uilabel(app.UIFigure);
            app.STEP13Label_2.Text = 'STEP 13';
            app.WARNINGLabel = uilabel(app.UIFigure);
            app.WARNINGLabel.Text = 'WARNING:';
            app.WILLSTOREDATAASFILESBUTLabel = uilabel(app.UIFigure);
            app.WILLSTOREDATAASFILESBUTLabel.Text = 'WILL STORE DATA AS FILES BUT ';
            app.WILLERASEALLDATAFROMLabel = uilabel(app.UIFigure);
            app.WILLERASEALLDATAFROMLabel.Text = 'WILL ERASE ALL DATA FROM ';
            app.TEMPORARYMEMORYLabel = uilabel(app.UIFigure);
            app.TEMPORARYMEMORYLabel.Text = 'TEMPORARY MEMORY.';
            app.SUBMITPEAKSButton = uibutton(app.UIFigure, 'push');
            app.SUBMITPEAKSButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITPEAKSButtonPushed, true);
            app.SUBMITPEAKSButton.Text = 'SUBMIT PEAKS';
            app.SAVITZKYGOLAYFILTERPARAMETERSLabel = uilabel(app.UIFigure);
            app.SAVITZKYGOLAYFILTERPARAMETERSLabel.Text = 'SAVITZKY-GOLAY FILTER PARAMETERS';
            app.WINDOWSIZEEditFieldLabel = uilabel(app.UIFigure);
            app.WINDOWSIZEEditFieldLabel.Text = 'WINDOW SIZE';
            app.WINDOWSIZEEditField = uieditfield(app.UIFigure, 'numeric');
            app.POLYNOMIALORDEREditFieldLabel = uilabel(app.UIFigure);
            app.POLYNOMIALORDEREditFieldLabel.Text = 'POLYNOMIAL ORDER';
            app.POLYNOMIALORDEREditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMAGING_ANALYSIS_FILTERS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end