classdef FIRST_PEAK_ANALYSIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        AlignedsmoothpeaksLabel         matlab.ui.control.Label
        SmoothandbaselineadjustedLabel  matlab.ui.control.Label
        AdjustBaselineButton            matlab.ui.control.Button
        WindowEditField                 matlab.ui.control.NumericEditField
        WindowEditFieldLabel            matlab.ui.control.Label
        WriteoutputButton               matlab.ui.control.Button
        AppendButton                    matlab.ui.control.Button
        PrevioustraceButton             matlab.ui.control.Button
        MinpeakseparationEditField      matlab.ui.control.NumericEditField
        MinpeakseparationEditFieldLabel  matlab.ui.control.Label
        NexttraceButton                 matlab.ui.control.Button
        FindpeaksButton                 matlab.ui.control.Button
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        OpenRawTracesButton             matlab.ui.control.Button
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        ycorr                                               % Data
        
        ycorrected
        
        harvest                                                 %Empty vector
        
        harvest_smooth
        
        harvest_firstpeak


        harvest_smooth_firstpeak
                 
         
        getwidth_firstwidth
            
        getwidth_smooth
        getwidth_smooth_firstwidth
        
        columns
        
        pks
        
        locs
        
        widths
        
        c
        
        folder
        
        T
        
        j
        
        y
        
        z
        
        yfit
        
        ycorrected_store
        
        yfit_store
        
        selectedpeakwidths
        
        selectedpeakwidths_smooth
        
        getwidth

        get_firstwidth
        
        smooth
        
        a
        
        locs_smooth
        
        pks_smooth
        
        widths_smooth
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenRawTracesButton
        function OpenRawTracesButtonPushed(app, event)
            cd '/Users/sapuruv/Desktop/Matlab_Workspace'
            
                                                         %Clear the table after last use.
        
            app.UIFigure.Visible = 'off';                                       %To bring the file selection pop-up forward.
        
            [file, app.folder] = uigetfile('.xlsx');
        
            app.UIFigure.Visible = 'on';
        
            p = string(file);

            [app.ycorr.Value]=xlsread(p);


            app.harvest.Value = [];
        
            app.columns.Value = size(app.ycorr.Value,2);
            
            app.c.Value = 1;
            
            plot(app.UIAxes, app.ycorr.Value(:,app.c.Value), 'LineWidth',1);
            
            app.ycorrected_store.Value =[];
            
            app.yfit_store.Value =[];
            
           
            app.harvest.Value = [];
            
            app.getwidth.Value =[];
            
            app.harvest_firstpeak.Value = [];
            
            app.harvest_smooth.Value = []; 
            
            app.harvest_smooth_firstpeak.Value = [];     
            
            app.getwidth_firstwidth.Value =[]; 
            
            app.getwidth_smooth.Value =[];
            
            app.getwidth_smooth_firstwidth.Value =[];
            
            app.Lamp.Color = 'r';
            app.Lamp_2.Color = 'r';
             
        end

        % Button pushed function: AdjustBaselineButton
        function AdjustBaselineButtonPushed(app, event)

            app.smooth.Value = sgolayfilt(app.ycorr.Value,2,41);
            
            [app.ycorrected.Value,app.yfit.Value] = bf(app.smooth.Value(:,app.c.Value), 10,'linear');
            
            app.z.Value = normalize(app.ycorrected.Value);
            
            app.ycorrected_store.Value = [app.ycorrected_store.Value ; app.z.Value];
            
            app.yfit_store.Value = [app.yfit_store.Value ; app.yfit.Value];
            
            plot(app.UIAxes_7, app.z.Value, 'LineWidth',1.5);
            
            hold(app.UIAxes_7, 'on')
            
            plot(app.UIAxes_7, normalize(app.ycorr.Value(:,app.c.Value)), 'LineWidth',0.5, 'Color', 'r' );
            
                     
        end

        % Button pushed function: FindpeaksButton
        function FindpeaksButtonPushed(app, event)
 
            peak_location = [];
            
            peak_value = [];
            
            width_value = [];
            
            width_value_smooth = [];
            
            peak_location_smooth = [];
            
            peak_value_smooth = [];

            app.j.Value = [];

            app.a.Value = [];
            
            
            [app.pks.Value,app.locs.Value,app.widths.Value] = findpeaks(app.z.Value, 'MinPeakDistance', app.MinpeakseparationEditField.Value);


            for k = 1:length(app.pks.Value)
                    
               if app.pks.Value(k) > app.ThresholdEditField.Value
            
                    peak_location = [peak_location, app.locs.Value(k)];
                    
                    peak_value = [peak_value, app.pks.Value(k)];
                    
                    width_value = [width_value, app.widths.Value(k)];
    
               end
               
               app.selectedpeakwidths.Value = width_value;
               
            end
            

            for i = 1:length(peak_location)
            
            app.j.Value(:,i) = app.z.Value(peak_location(i) - app.WindowEditField.Value : peak_location(i) + app.WindowEditField.Value);
           
            end


%             for i = 1:length(peak_location_smooth)
%             
%             
%             app.a.Value(:,i) = app.smooth.Value(peak_location_smooth(i)-app.WindowEditField.Value:peak_location_smooth(i)+app.WindowEditField.Value);
%             
%            
%             end

            
%             plot(app.UIAxes_7, app.z.Value, 'g', 'LineWidth',1.5)
%             hold(app.UIAxes_7, 'on')
            plot(app.UIAxes_7, peak_location, peak_value, 'o','MarkerFaceColor', 'r', 'LineWidth',1.5, 'MarkerEdgeColor', 'k');
            hold(app.UIAxes_7, 'off')
            
            plot(app.UIAxes_3,  app.j.Value, 'LineWidth', 2);
            hold(app.UIAxes_3, 'off')


        end

        % Button pushed function: NexttraceButton
        function NexttraceButtonPushed(app, event)
           
           app.c.Value = app.c.Value + 1;

           plot(app.UIAxes, app.ycorr.Value(:,app.c.Value), 'LineWidth',1);
           
           app.Lamp.Color = 'r';
           app.Lamp_2.Color = 'g';
           
        end

        % Button pushed function: PrevioustraceButton
        function PrevioustraceButtonPushed(app, event)
            
            app.c.Value = app.c.Value - 1; 
            
            plot(app.UIAxes, app.ycorr.Value(:,app.c.Value), 'LineWidth',1);
            
        end

        % Button pushed function: AppendButton
        function AppendButtonPushed(app, event)
            
            app.harvest.Value = [app.harvest.Value app.j.Value]; 
            app.harvest_firstpeak.Value = [app.harvest_firstpeak.Value app.j.Value(:,1)];
            
%             app.harvest_smooth.Value = [app.harvest_smooth.Value app.smooth.Value]; 
%             app.harvest_smooth_firstpeak.Value = [app.harvest_smooth_firstpeak.Value app.a.Value(:,1)];
                 
%             app.getwidth.Value =[app.getwidth.Value app.selectedpeakwidths.Value];
%             app.getwidth_firstwidth.Value =[app.getwidth_firstwidth.Value app.selectedpeakwidths.Value(:,1)]; 
%             
%             app.getwidth_smooth.Value =[app.getwidth_smooth.Value app.selectedpeakwidths_smooth.Value];
%             app.getwidth_smooth_firstwidth.Value =[app.getwidth_smooth_firstwidth.Value app.selectedpeakwidths_smooth.Value(:,1)];
            
%           
            app.Lamp.Color = 'g';
            app.Lamp_2.Color = 'r';
          
        end

        % Button pushed function: WriteoutputButton
        function WriteoutputButtonPushed(app, event)
           
            cd '/Users/sapuruv/Desktop/Matlab_Workspace/First_Peak_Outputs'
            
            xlswrite('isolated_peaks.xlsx', app.harvest.Value);
            
%             xlswrite('isolated_peaks_smooth.xlsx', app.harvest_smooth.Value);
            
            xlswrite('isolated_firstpeaks.xlsx', app.harvest_firstpeak.Value);
           
%             xlswrite('isolated_firstpeaks_smooth.xlsx', app.harvest_smooth_firstpeak.Value);
            
            
            cd '/Users/sapuruv/Desktop/Matlab_Workspace'
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Frame no.')
            ylabel(app.UIAxes, 'Normalized intensity')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'Frame no.')
            ylabel(app.UIAxes_3, 'Normalized intensity')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_7, 'Frame no.')
            ylabel(app.UIAxes_7, 'Normalized intensity')
            app.OpenRawTracesButton = uibutton(app.UIFigure, 'push');
            app.OpenRawTracesButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRawTracesButtonPushed, true);
            app.OpenRawTracesButton.Text = 'Open Raw Traces';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.FindpeaksButton = uibutton(app.UIFigure, 'push');
            app.FindpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindpeaksButtonPushed, true);
            app.FindpeaksButton.Text = 'Find peaks';
            app.NexttraceButton = uibutton(app.UIFigure, 'push');
            app.NexttraceButton.ButtonPushedFcn = createCallbackFcn(app, @NexttraceButtonPushed, true);
            app.NexttraceButton.Text = 'Next trace';
            app.MinpeakseparationEditFieldLabel = uilabel(app.UIFigure);
            app.MinpeakseparationEditFieldLabel.Text = 'Min peak separation';
            app.MinpeakseparationEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrevioustraceButton = uibutton(app.UIFigure, 'push');
            app.PrevioustraceButton.ButtonPushedFcn = createCallbackFcn(app, @PrevioustraceButtonPushed, true);
            app.PrevioustraceButton.Text = 'Previous trace';
            app.AppendButton = uibutton(app.UIFigure, 'push');
            app.AppendButton.ButtonPushedFcn = createCallbackFcn(app, @AppendButtonPushed, true);
            app.AppendButton.Text = 'Append';
            app.WriteoutputButton = uibutton(app.UIFigure, 'push');
            app.WriteoutputButton.ButtonPushedFcn = createCallbackFcn(app, @WriteoutputButtonPushed, true);
            app.WriteoutputButton.Text = 'Write output';
            app.WindowEditFieldLabel = uilabel(app.UIFigure);
            app.WindowEditFieldLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustBaselineButton = uibutton(app.UIFigure, 'push');
            app.AdjustBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustBaselineButtonPushed, true);
            app.AdjustBaselineButton.Text = 'Adjust Baseline';
            app.SmoothandbaselineadjustedLabel = uilabel(app.UIFigure);
            app.SmoothandbaselineadjustedLabel.Text = 'Smooth and baseline adjusted';
            app.AlignedsmoothpeaksLabel = uilabel(app.UIFigure);
            app.AlignedsmoothpeaksLabel.Text = 'Aligned smooth peaks';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIRST_PEAK_ANALYSIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end