classdef IMAGING_ANALYSIS_V6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UPDATEFILEButton                matlab.ui.control.Button
        SUBMITPEAKSButton               matlab.ui.control.Button
        TEMPORARYMEMORYLabel            matlab.ui.control.Label
        WILLERASEALLDATAFROMLabel       matlab.ui.control.Label
        WILLSTOREDATAASFILESBUTLabel    matlab.ui.control.Label
        WARNINGLabel                    matlab.ui.control.Label
        STEP13Label_2                   matlab.ui.control.Label
        STEP125CONTINUETILLALLTRACESAREDONELabel  matlab.ui.control.Label
        COMPILEDATAButton               matlab.ui.control.Button
        STEP15Label                     matlab.ui.control.Label
        STEP14Label                     matlab.ui.control.Label
        STEP12Label                     matlab.ui.control.Label
        STEP11Label                     matlab.ui.control.Label
        STEP10Label                     matlab.ui.control.Label
        STEP8Label                      matlab.ui.control.Label
        STEP7Label_2                    matlab.ui.control.Label
        STEP6Label_2                    matlab.ui.control.Label
        STEP5Label_2                    matlab.ui.control.Label
        BADOSCILLATIONButton            matlab.ui.control.Button
        DETECTANDADDPEAKSButton         matlab.ui.control.Button
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        FITBASELINEButton               matlab.ui.control.Button
        Lamp2                           matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        SEQUENCEYOUWISHTOPROCESSLabel   matlab.ui.control.Label
        ONDESKTOPTOSTORETHEIMAGELabel   matlab.ui.control.Label
        CHOOSEOSSwitch                  matlab.ui.control.ToggleSwitch
        CHOOSEOSSwitchLabel             matlab.ui.control.Label
        TOTALFRAMES                     matlab.ui.control.NumericEditField
        AREATHRESHOLDEditFieldLabel_2   matlab.ui.control.Label
        CREATEAFOLDERMOVIE_TESTLabel    matlab.ui.control.Label
        NOTELabel                       matlab.ui.control.Label
        STEP0Label                      matlab.ui.control.Label
        GENERATEOVERLAYButton           matlab.ui.control.Button
        CHOOSEFILEButton                matlab.ui.control.Button
        MAXIMUMINTENSITYPROJECTIONFILELOCATIONLabel  matlab.ui.control.Label
        GENERATEINTERPEAKHISTOGRAMDATAFILEButton  matlab.ui.control.Button
        GENERATERESPONSEDATAFILEButton  matlab.ui.control.Button
        STEP5Label                      matlab.ui.control.Label
        STEP9Label                      matlab.ui.control.Label
        NORESPONSEBADButton             matlab.ui.control.Button
        GOODOSCILLATIONButton           matlab.ui.control.Button
        SINGLEPEAKButton                matlab.ui.control.Button
        CLASSIFICATIONSLabel            matlab.ui.control.Label
        MINIMUMINTERPEAKDISTANCEEditField  matlab.ui.control.NumericEditField
        MINIMUMINTERPEAKDISTANCEEditFieldLabel  matlab.ui.control.Label
        PEAKTHRESHOLDEditField          matlab.ui.control.NumericEditField
        PEAKTHRESHOLDEditFieldLabel     matlab.ui.control.Label
        STEP4Label                      matlab.ui.control.Label
        NEXTTRACEButton                 matlab.ui.control.Button
        PREVIOUSTRACEButton             matlab.ui.control.Button
        PLOTNORMALIZEDTRACESButton      matlab.ui.control.Button
        PLOTTRACESButton                matlab.ui.control.Button
        STEP3Label                      matlab.ui.control.Label
        AREATHRESHOLDEditField          matlab.ui.control.NumericEditField
        AREATHRESHOLDEditFieldLabel     matlab.ui.control.Label
        STEP2Label                      matlab.ui.control.Label
        STEP1Label                      matlab.ui.control.Label
        GENERATEAREAHISTOGRAMButton     matlab.ui.control.Button
        GENERATEDOGButton               matlab.ui.control.Button
        KERNELSIZE_2EditField           matlab.ui.control.NumericEditField
        KERNELSIZEEditField_4Label      matlab.ui.control.Label
        SIGMA_2EditField                matlab.ui.control.NumericEditField
        SIGMAEditField_2Label           matlab.ui.control.Label
        SIGMA_1EditField                matlab.ui.control.NumericEditField
        SIGMAEditFieldLabel             matlab.ui.control.Label
        GAUSSIAN2Label                  matlab.ui.control.Label
        GAUSSIAN1Label                  matlab.ui.control.Label
        KERNELSIZE_1EditField           matlab.ui.control.NumericEditField
        KERNELSIZE_1EditFieldLabel      matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        max_ip                % Original maximum intensity projection file.
        folder                % Switching Directories
        adj_image             % After adjustment and DOG.
        BWfinal               % Image with segments.
        test_direc            % Image sequence directory.
        normalized_traces
        current_trace
        interpeak_distance_values
        
        y_store
        y_norm_store
        ycorr
        yfit
        ycorr_store
        yfit_store
        
        single_peak
        single_peak_indices
        good_oscillation
        good_oscillation_indices
        bad_oscillation
        bad_oscillation_indices
        non_responsive
        non_responsive_indices
        idx
        BWlabeled
        ipd
        Area1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CHOOSEFILEButton
        function CHOOSEFILEButtonPushed(app, event)
   cd '/Users/sapuruv/Desktop/Matlab_Workspace/MAXIPs'
        
        app.UIFigure.Visible = 'off';                                       %To bring the file selection pop-up forward.
        
        [file, app.folder] = uigetfile('.png');
        
        app.UIFigure.Visible = 'on';
        
        app.max_ip.Value = imread(file);
        
        figure
        hAxes = gca;
        imshow(file)
        
        %Empty vectors that get filled later on.
 
        app.interpeak_distance_values.Value = [];
        app.y_store.Value = [];
        app.yfit_store.Value = [];
        app.ycorr_store.Value = [];
        
        app.single_peak.Value = [];
        app.single_peak_indices.Value = [];
        app.good_oscillation.Value = [];
        app.good_oscillation_indices.Value = [];
        app.bad_oscillation.Value = [];
        app.bad_oscillation_indices.Value = [];
        app.non_responsive.Value = [];
        app.non_responsive_indices.Value = [];
        app.bad_oscillation_indices.Value = [];


        end

        % Button pushed function: GENERATEDOGButton
        function GENERATEDOGButtonPushed(app, event)
  
            gaussian1 = fspecial('Gaussian', app.KERNELSIZE_1EditField.Value, app.SIGMA_1EditField.Value);
            gaussian2 = fspecial('Gaussian', app.KERNELSIZE_2EditField.Value, app.SIGMA_2EditField.Value);

            DOG = gaussian1 - gaussian2; % Difference of Gaussian

            dogFilterImage = conv2(double(app.max_ip.Value(:,:,1)), DOG, 'same');

            B = imsharpen(dogFilterImage); 

            app.adj_image.Value = imadjust(B); % Image histogram adjustment.

            figure; 
            hAxes = gca;
            imshow( app.adj_image.Value, 'Parent', gca );
            
        end

        % Button pushed function: GENERATEOVERLAYButton
        function GENERATEOVERLAYButtonPushed(app, event)
            

            [~,threshold] = edge(app.adj_image.Value,'sobel');
            fudgeFactor = 0.5;
            BWs = edge(app.adj_image.Value,'sobel',threshold * fudgeFactor);

            se90 = strel('line',3,90);
            se0 = strel('line',3,0);
            
            BWsdil = imdilate(BWs,[se90 se0]);
            BWdfill = imfill(BWsdil,'holes');
            
%             figure;
%             hAxes = gca;
%             imshow(BWdfill)
%             title('Binary Image with Filled Holes')
            
            BWnobord = imclearborder(BWdfill,4);
%             
%             figure;
%             hAxes = gca;
%             imshow(BWnobord)
%             title('Cleared Border Image')
            
            seD = strel('diamond',1);
            
            app.BWfinal.Value = imerode(BWnobord,seD);
            app.BWfinal.Value = imerode(app.BWfinal.Value,seD);
            
%             figure; 
%             hAxes = gca;
%             imshow(app.BWfinal.Value)
%             title('Segmented Image');
            
            figure; 
            hAxes = gca;
            imshow(labeloverlay(app.adj_image.Value,app.BWfinal.Value))
            title('Mask Over Original Image')


%             app.BWlabeled.Value = bwlabel(app.BWfinal.Value); 
%             
%             s = regionprops(app.BWlabeled.Value, 'Centroid');
%             imshow(labeloverlay(app.adj_image.Value,app.BWfinal.Value))
%             hold on
%             for k = 1:numel(s)
%             c = s(k).Centroid;
%             text(c(1), c(2), sprintf('%d', k), ...
%                     'HorizontalAlignment', 'center', ...
%                 'VerticalAlignment', 'middle');
%             end
%             hold off
            
        end

        % Button pushed function: GENERATEAREAHISTOGRAMButton
        function GENERATEAREAHISTOGRAMButtonPushed(app, event)
            
            app.BWlabeled.Value = bwlabel(app.BWfinal.Value);   


            stats = regionprops(app.BWlabeled.Value);
            
            app.Area1.Value = [stats.Area].';
            
            r = iqr(app.Area1.Value); %Inter-quartile Range
            bin_width_area = round((2*r/(nthroot(length(app.Area1.Value),3))));                 %Following Freedman–Diaconis rule for calculating bin_width
            
            figure;
            histogram(app.Area1.Value,'BinWidth', bin_width_area)


        end

        % Button pushed function: PLOTTRACESButton
        function PLOTTRACESButtonPushed(app, event)
            tic 
            
            app.idx.Value = find(app.Area1.Value >= app.AREATHRESHOLDEditField.Value);
            
            app.idx.Value
            
            s = regionprops(app.BWlabeled.Value, 'Centroid');
            
            imshow(labeloverlay(app.adj_image.Value,app.BWfinal.Value))
            hold on
            for k = 1:numel(app.idx.Value)
            c = s(app.idx.Value(k)).Centroid;
            text(c(1), c(2), sprintf('%d', k), ...
                    'HorizontalAlignment', 'center', ...
                'VerticalAlignment', 'middle');
            end
            hold off
           
            cd(app.test_direc.Value)  %Value depends on OS


%             filelist = ls(pwd);
%             
%             filelist(1:2,:) = [];
% 
%             for i = 1:app.TOTALFRAMES.Value
%                 nums = regexp(filelist(i,:),'\d');
%                 tmpname = filelist(i,nums);
%                 name(i) = str2num(tmpname(7:end-9));
%             end
%             
%             name_beforeorder = name';
%             x = 1:app.TOTALFRAMES.Value;
%             x = x';
%             before_order = cat(2,x,name_beforeorder);
%             ordering = sortrows(before_order,2);


            tifFiles = dir('*.tif'); 
            numfiles = length(tifFiles);
            keep_images = cell(1, numfiles);


            for i = 1:app.TOTALFRAMES.Value
                keep_images{i}=imread(tifFiles(i).name); 
            end


%             for i = 1:app.TOTALFRAMES.Value
%                 keep_images{i}=imread(filelist(ordering(i,1),:));
%             end

            for i = 1:numel(app.idx.Value)
                temp = NaN(size(app.BWfinal.Value,1),size(app.BWfinal.Value,2));
                temp(app.BWlabeled.Value == app.idx.Value(i)) = 1;
                keep_masks{i} = temp;

                for n = 1:app.TOTALFRAMES.Value
                    temp2 = double(keep_images{n}(:,:,1)).*temp;
                    keep_means(n,i) = mean(temp2,'all','omitnan');
                end
            end
            
            writematrix(keep_means, 'raw_mean_intensities.xlsx');
            
            figure; 
            hAxes = gca;
            plot(keep_means(:,:), 'LineWidth',1.5)
            
            app.normalized_traces.Value = [];
            
            for trace = 1:length(app.idx.Value)
                j = normalize(keep_means(:,trace));
                app.normalized_traces.Value = [app.normalized_traces.Value j];
            end
            
            writematrix(app.normalized_traces.Value, 'normalized_mean_intensities.xlsx');
            
            toc
            
        end

        % Value changed function: CHOOSEOSSwitch
        function CHOOSEOSSwitchValueChanged(app, event)
            
            value = app.CHOOSEOSSwitch.Value;
         
            if strcmp(value, 'Windows')
               app.test_direc.Value = 'E:\Movie_Processing\MOVIE_TEST';
               app.Lamp.Color = 'g';
               app.Lamp2.Color = 'r';
            else
               app.test_direc.Value = '/Users/sapuruv/Desktop/Matlab_Workspace/MOVIE_TEST';
               app.Lamp.Color = 'r';
               app.Lamp2.Color = 'g';
            end

            app.test_direc.Value
            
        end

        % Button pushed function: PLOTNORMALIZEDTRACESButton
        function PLOTNORMALIZEDTRACESButtonPushed(app, event)
            
            app.current_trace.Value = 1;
            
            plot(app.UIAxes, app.normalized_traces.Value(:,app.current_trace.Value), 'LineWidth',2 )
            
            
        end

        % Button pushed function: FITBASELINEButton
        function FITBASELINEButtonPushed(app, event)
            cd(app.test_direc.Value)


            [app.ycorr.Value,app.yfit.Value] = bf(app.normalized_traces.Value(:,app.current_trace.Value), 10,'linear');
            
            app.ycorr_store.Value = [app.ycorr_store.Value ; app.ycorr.Value];
            
            app.yfit_store.Value = [app.yfit_store.Value ; app.yfit.Value];


           figure;
           plot(app.normalized_traces.Value(:,app.current_trace.Value))
           hold on;
           plot(app.ycorr.Value)
           plot(app.yfit.Value)
           hold off
           
           plot(app.UIAxes_2, app.ycorr.Value, 'LineWidth',2)
          
        end

        % Button pushed function: DETECTANDADDPEAKSButton
        function DETECTANDADDPEAKSButtonPushed(app, event)
           
           [pks,locs] = findpeaks(app.ycorr.Value, 'MinPeakDistance', app.MINIMUMINTERPEAKDISTANCEEditField.Value);

           peak_location = [];
           
           %Add code to get inter-peak distances.
           
           for c = 1:length(pks)
                    
               if pks(c) > app.PEAKTHRESHOLDEditField.Value
            
                    peak_location = [peak_location, locs(c)];
    
               end
           end
           
           distance = diff(peak_location);
           
           app.ipd.Value = [repelem(app.current_trace.Value, length(distance)); distance];


           peak_values = app.ycorr.Value(peak_location);
           
          
           plot(app.UIAxes_2, app.ycorr.Value)
           hold(app.UIAxes_2, 'on' )
           plot(app.UIAxes_2, peak_location, peak_values,'o', 'LineWidth',2)
           hold(app.UIAxes_2, 'off' )


        end

        % Button pushed function: SINGLEPEAKButton
        function SINGLEPEAKButtonPushed(app, event)
            app.single_peak.Value = [app.single_peak.Value ; app.normalized_traces.Value(:,app.current_trace.Value)];
            app.single_peak_indices.Value = [app.single_peak_indices.Value app.current_trace.Value];
            app.Lamp_2.Color = 'g';
            app.Lamp_3.Color = 'r';
            app.Lamp_4.Color = 'r';
            app.Lamp_6.Color = 'r';
            %%%% Write code to store the index of the trace.
        end

        % Button pushed function: GOODOSCILLATIONButton
        function GOODOSCILLATIONButtonPushed(app, event)
            app.good_oscillation.Value = [app.good_oscillation.Value ; app.normalized_traces.Value(:,app.current_trace.Value)];
            app.good_oscillation_indices.Value = [app.good_oscillation_indices.Value app.current_trace.Value];
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'g';
            app.Lamp_4.Color = 'r';
            app.Lamp_6.Color = 'r';
            %%%% Write code to store the index of the trace.
        end

        % Button pushed function: BADOSCILLATIONButton
        function BADOSCILLATIONButtonPushed(app, event)
            app.bad_oscillation.Value = [app.bad_oscillation.Value ; app.normalized_traces.Value(:,app.current_trace.Value)];
            app.bad_oscillation_indices.Value = [app.bad_oscillation_indices.Value app.current_trace.Value];
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r';
            app.Lamp_4.Color = 'g';
            app.Lamp_6.Color = 'r';
            %%%% Write code to store the index of the trace.
        end

        % Button pushed function: NORESPONSEBADButton
        function NORESPONSEBADButtonPushed(app, event)
            app.non_responsive.Value = [app.non_responsive.Value ; app.normalized_traces.Value(:,app.current_trace.Value)];
            app.non_responsive_indices.Value = [app.non_responsive_indices.Value app.current_trace.Value];
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r';
            app.Lamp_4.Color = 'r';
            app.Lamp_6.Color = 'g';
            %%%% Write code to store the index of the trace.
        end

        % Button pushed function: NEXTTRACEButton
        function NEXTTRACEButtonPushed(app, event)
            
            app.current_trace.Value = app.current_trace.Value + 1;
            
            plot(app.UIAxes, app.normalized_traces.Value(:, app.current_trace.Value) )
            
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r';
            app.Lamp_4.Color = 'r';
            app.Lamp_6.Color = 'r';
            
        end

        % Button pushed function: PREVIOUSTRACEButton
        function PREVIOUSTRACEButtonPushed(app, event)
            
            app.current_trace.Value = app.current_trace.Value - 1;
            
            plot(app.UIAxes, app.normalized_traces.Value(:,app.current_trace.Value) )
            
        end

        % Button pushed function: GENERATERESPONSEDATAFILEButton
        function GENERATERESPONSEDATAFILEButtonPushed(app, event)
            
            single = length(app.single_peak_indices.Value);
            
            oscillations = length(app.good_oscillation_indices.Value) + length(app.bad_oscillation_indices.Value);
            
            dead = length(app.non_responsive_indices.Value);
            
            response = categorical({'Single Peak','Oscillations','Non-Responsive'});
            response = reordercats(response,{'Single Peak','Oscillations','Non-Responsive'});
            response_data = [single oscillations dead];
            
            bar(app.UIAxes_3, response,response_data)
            
        end

        % Button pushed function: GENERATEINTERPEAKHISTOGRAMDATAFILEButton
        function GENERATEINTERPEAKHISTOGRAMDATAFILEButtonPushed(app, event)
            ipd_movie = app.interpeak_distance_values.Value(:);
            
            k = iqr(ipd_movie);
            
            histogram(app.UIAxes_4, ipd_movie)
        end

        % Button pushed function: COMPILEDATAButton
        function COMPILEDATAButtonPushed(app, event)
%       writematrix(app.interpeak_distance_values.Value, 'interpeak_distance_values.xlsx');
            
%      s.interpeak_distance_values =  app.interpeak_distance_values.Value; 
%      s.y_store = app.y_store.Value; 
%      s.yfit_store = app.yfit_store.Value; 
%      s.y_corr_store = app.ycorr_store.Value ;
%         
%      s.single_peak = app.single_peak.Value; 
%      s.single_peak_indices =app.single_peak_indices.Value; 
%      s.good_oscillation = app.good_oscillation.Value; 
%      s.good_oscillation_indices = app.good_oscillation_indices.Value; 
%      s.bad_oscillation = app.bad_oscillation.Value; 
%      s.bad_oscillation_indices = app.bad_oscillation_indices.Value; 
%      s.non_responsive = app.non_responsive.Value; 
%      s.non_responsive_indices = app.non_responsive_indices.Value; 
%      s.bad_oscillation_indices = app.bad_oscillation_indices.Value; 
%        
%      writematrix(struct2matrix(s), 'data.txt');


     %Clearing Data
     clear 
     clc
            
        end

        % Button pushed function: SUBMITPEAKSButton
        function SUBMITPEAKSButtonPushed(app, event)
          
            app.interpeak_distance_values.Value = [app.interpeak_distance_values.Value app.ipd.Value];
          
        end

        % Button pushed function: UPDATEFILEButton
        function UPDATEFILEButtonPushed(app, event)
            writematrix(app.interpeak_distance_values.Value, 'interpeak_distance_values.xlsx');
            
            writematrix(app.y_store.Value, 'y_store.xlsx');
            writematrix(app.yfit_store.Value, 'yfit_store.xlsx');
            writematrix(app.ycorr_store.Value, 'ycorr_store.xlsx') ;
            writematrix(app.single_peak.Value, 'single_peak.xlsx');
            writematrix(app.single_peak_indices.Value, 'single_peak_indices.xlsx');
            writematrix(app.good_oscillation.Value, 'good_oscillation.xlsx');
            writematrix(app.good_oscillation_indices.Value, 'good_oscillation_indices.xlsx');
            writematrix(app.bad_oscillation.Value, 'bad_oscillation.Value.xlsx');
            writematrix(app.bad_oscillation_indices.Value, 'bad_oscillation_indices.xlsx');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'NORMALIZED INDIVIDUAL TRACES')
            xlabel(app.UIAxes, 'FRAME NUMBER')
            ylabel(app.UIAxes, 'STANDARD DEVIATION')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'PEAK THRESHOLDING AFTER BASELINE ADJUSTMENT ')
            xlabel(app.UIAxes_2, 'FRAME NUMBER')
            ylabel(app.UIAxes_2, 'SD')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'RESPONSE BAR PLOT')
            xlabel(app.UIAxes_3, 'FRAMES (0.25 ms/Frame)')
            ylabel(app.UIAxes_3, 'SD')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'INTERPEAK DURATION HISTOGRAM')
            xlabel(app.UIAxes_4, 'FRAMES (0.25 ms/Frame)')
            ylabel(app.UIAxes_4, 'SD')
            app.KERNELSIZE_1EditFieldLabel = uilabel(app.UIFigure);
            app.KERNELSIZE_1EditFieldLabel.Text = 'KERNEL SIZE';
            app.KERNELSIZE_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.GAUSSIAN1Label = uilabel(app.UIFigure);
            app.GAUSSIAN1Label.Text = 'GAUSSIAN 1';
            app.GAUSSIAN2Label = uilabel(app.UIFigure);
            app.GAUSSIAN2Label.Text = 'GAUSSIAN 2';
            app.SIGMAEditFieldLabel = uilabel(app.UIFigure);
            app.SIGMAEditFieldLabel.Text = 'SIGMA';
            app.SIGMA_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.SIGMAEditField_2Label = uilabel(app.UIFigure);
            app.SIGMAEditField_2Label.Text = 'SIGMA';
            app.SIGMA_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.KERNELSIZEEditField_4Label = uilabel(app.UIFigure);
            app.KERNELSIZEEditField_4Label.Text = 'KERNEL SIZE';
            app.KERNELSIZE_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.GENERATEDOGButton = uibutton(app.UIFigure, 'push');
            app.GENERATEDOGButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEDOGButtonPushed, true);
            app.GENERATEDOGButton.Text = 'GENERATE ''DOG''';
            app.GENERATEAREAHISTOGRAMButton = uibutton(app.UIFigure, 'push');
            app.GENERATEAREAHISTOGRAMButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEAREAHISTOGRAMButtonPushed, true);
            app.GENERATEAREAHISTOGRAMButton.Text = 'GENERATE AREA HISTOGRAM';
            app.STEP1Label = uilabel(app.UIFigure);
            app.STEP1Label.Text = 'STEP 1';
            app.STEP2Label = uilabel(app.UIFigure);
            app.STEP2Label.Text = 'STEP 2';
            app.AREATHRESHOLDEditFieldLabel = uilabel(app.UIFigure);
            app.AREATHRESHOLDEditFieldLabel.Text = 'AREA THRESHOLD';
            app.AREATHRESHOLDEditField = uieditfield(app.UIFigure, 'numeric');
            app.STEP3Label = uilabel(app.UIFigure);
            app.STEP3Label.Text = 'STEP 3';
            app.PLOTTRACESButton = uibutton(app.UIFigure, 'push');
            app.PLOTTRACESButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTTRACESButtonPushed, true);
            app.PLOTTRACESButton.Text = 'PLOT TRACES';
            app.PLOTNORMALIZEDTRACESButton = uibutton(app.UIFigure, 'push');
            app.PLOTNORMALIZEDTRACESButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTNORMALIZEDTRACESButtonPushed, true);
            app.PLOTNORMALIZEDTRACESButton.Text = 'PLOT NORMALIZED TRACES';
            app.PREVIOUSTRACEButton = uibutton(app.UIFigure, 'push');
            app.PREVIOUSTRACEButton.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSTRACEButtonPushed, true);
            app.PREVIOUSTRACEButton.Text = '< PREVIOUS TRACE';
            app.NEXTTRACEButton = uibutton(app.UIFigure, 'push');
            app.NEXTTRACEButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTTRACEButtonPushed, true);
            app.NEXTTRACEButton.Text = 'NEXT TRACE >';
            app.STEP4Label = uilabel(app.UIFigure);
            app.STEP4Label.Text = 'STEP 4';
            app.PEAKTHRESHOLDEditFieldLabel = uilabel(app.UIFigure);
            app.PEAKTHRESHOLDEditFieldLabel.Text = 'PEAK THRESHOLD';
            app.PEAKTHRESHOLDEditField = uieditfield(app.UIFigure, 'numeric');
            app.MINIMUMINTERPEAKDISTANCEEditFieldLabel = uilabel(app.UIFigure);
            app.MINIMUMINTERPEAKDISTANCEEditFieldLabel.Text = 'MINIMUM INTERPEAK DISTANCE';
            app.MINIMUMINTERPEAKDISTANCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.CLASSIFICATIONSLabel = uilabel(app.UIFigure);
            app.CLASSIFICATIONSLabel.Text = 'CLASSIFICATIONS';
            app.SINGLEPEAKButton = uibutton(app.UIFigure, 'push');
            app.SINGLEPEAKButton.ButtonPushedFcn = createCallbackFcn(app, @SINGLEPEAKButtonPushed, true);
            app.SINGLEPEAKButton.Text = 'SINGLE PEAK';
            app.GOODOSCILLATIONButton = uibutton(app.UIFigure, 'push');
            app.GOODOSCILLATIONButton.ButtonPushedFcn = createCallbackFcn(app, @GOODOSCILLATIONButtonPushed, true);
            app.GOODOSCILLATIONButton.Text = 'GOOD OSCILLATION';
            app.NORESPONSEBADButton = uibutton(app.UIFigure, 'push');
            app.NORESPONSEBADButton.ButtonPushedFcn = createCallbackFcn(app, @NORESPONSEBADButtonPushed, true);
            app.NORESPONSEBADButton.Text = 'NO RESPONSE / BAD';
            app.STEP9Label = uilabel(app.UIFigure);
            app.STEP9Label.Text = 'STEP 9';
            app.STEP5Label = uilabel(app.UIFigure);
            app.STEP5Label.Text = 'STEP 5';
            app.GENERATERESPONSEDATAFILEButton = uibutton(app.UIFigure, 'push');
            app.GENERATERESPONSEDATAFILEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATERESPONSEDATAFILEButtonPushed, true);
            app.GENERATERESPONSEDATAFILEButton.Text = 'GENERATE RESPONSE DATA FILE';
            app.GENERATEINTERPEAKHISTOGRAMDATAFILEButton = uibutton(app.UIFigure, 'push');
            app.GENERATEINTERPEAKHISTOGRAMDATAFILEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEINTERPEAKHISTOGRAMDATAFILEButtonPushed, true);
            app.GENERATEINTERPEAKHISTOGRAMDATAFILEButton.Text = 'GENERATE INTERPEAK HISTOGRAM DATA FILE';
            app.MAXIMUMINTENSITYPROJECTIONFILELOCATIONLabel = uilabel(app.UIFigure);
            app.MAXIMUMINTENSITYPROJECTIONFILELOCATIONLabel.Text = 'MAXIMUM INTENSITY PROJECTION FILE LOCATION';
            app.CHOOSEFILEButton = uibutton(app.UIFigure, 'push');
            app.CHOOSEFILEButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEFILEButtonPushed, true);
            app.CHOOSEFILEButton.Text = 'CHOOSE FILE';
            app.GENERATEOVERLAYButton = uibutton(app.UIFigure, 'push');
            app.GENERATEOVERLAYButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEOVERLAYButtonPushed, true);
            app.GENERATEOVERLAYButton.Text = 'GENERATE OVERLAY';
            app.STEP0Label = uilabel(app.UIFigure);
            app.STEP0Label.Text = 'STEP 0';
            app.NOTELabel = uilabel(app.UIFigure);
            app.NOTELabel.Text = 'NOTE:';
            app.CREATEAFOLDERMOVIE_TESTLabel = uilabel(app.UIFigure);
            app.CREATEAFOLDERMOVIE_TESTLabel.Text = 'CREATE A FOLDER ''MOVIE_TEST''  ';
            app.AREATHRESHOLDEditFieldLabel_2 = uilabel(app.UIFigure);
            app.AREATHRESHOLDEditFieldLabel_2.Text = 'TOTAL FRAMES';
            app.TOTALFRAMES = uieditfield(app.UIFigure, 'numeric');
            app.CHOOSEOSSwitchLabel = uilabel(app.UIFigure);
            app.CHOOSEOSSwitchLabel.Text = 'CHOOSE OS';
            app.CHOOSEOSSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CHOOSEOSSwitch.Items = {'MacOS', 'Windows'};
            app.CHOOSEOSSwitch.ValueChangedFcn = createCallbackFcn(app, @CHOOSEOSSwitchValueChanged, true);
            app.CHOOSEOSSwitch.Value = 'MacOS';
            app.ONDESKTOPTOSTORETHEIMAGELabel = uilabel(app.UIFigure);
            app.ONDESKTOPTOSTORETHEIMAGELabel.Text = 'ON DESKTOP TO STORE THE IMAGE';
            app.SEQUENCEYOUWISHTOPROCESSLabel = uilabel(app.UIFigure);
            app.SEQUENCEYOUWISHTOPROCESSLabel.Text = 'SEQUENCE YOU WISH TO PROCESS.';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.FITBASELINEButton = uibutton(app.UIFigure, 'push');
            app.FITBASELINEButton.ButtonPushedFcn = createCallbackFcn(app, @FITBASELINEButtonPushed, true);
            app.FITBASELINEButton.Text = 'FIT BASELINE';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.DETECTANDADDPEAKSButton = uibutton(app.UIFigure, 'push');
            app.DETECTANDADDPEAKSButton.ButtonPushedFcn = createCallbackFcn(app, @DETECTANDADDPEAKSButtonPushed, true);
            app.DETECTANDADDPEAKSButton.Text = 'DETECT AND ADD PEAKS';
            app.BADOSCILLATIONButton = uibutton(app.UIFigure, 'push');
            app.BADOSCILLATIONButton.ButtonPushedFcn = createCallbackFcn(app, @BADOSCILLATIONButtonPushed, true);
            app.BADOSCILLATIONButton.Text = 'BAD OSCILLATION';
            app.STEP5Label_2 = uilabel(app.UIFigure);
            app.STEP5Label_2.Text = 'STEP 5';
            app.STEP6Label_2 = uilabel(app.UIFigure);
            app.STEP6Label_2.Text = 'STEP 6';
            app.STEP7Label_2 = uilabel(app.UIFigure);
            app.STEP7Label_2.Text = 'STEP 7';
            app.STEP8Label = uilabel(app.UIFigure);
            app.STEP8Label.Text = 'STEP 8';
            app.STEP10Label = uilabel(app.UIFigure);
            app.STEP10Label.Text = 'STEP 10';
            app.STEP11Label = uilabel(app.UIFigure);
            app.STEP11Label.Text = 'STEP 11';
            app.STEP12Label = uilabel(app.UIFigure);
            app.STEP12Label.Text = 'STEP 12';
            app.STEP14Label = uilabel(app.UIFigure);
            app.STEP14Label.Text = 'STEP 14';
            app.STEP15Label = uilabel(app.UIFigure);
            app.STEP15Label.Text = 'STEP 15';
            app.COMPILEDATAButton = uibutton(app.UIFigure, 'push');
            app.COMPILEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @COMPILEDATAButtonPushed, true);
            app.COMPILEDATAButton.Text = 'COMPILE DATA';
            app.STEP125CONTINUETILLALLTRACESAREDONELabel = uilabel(app.UIFigure);
            app.STEP125CONTINUETILLALLTRACESAREDONELabel.Text = 'STEP 12.5. CONTINUE TILL ALL TRACES ARE DONE.';
            app.STEP13Label_2 = uilabel(app.UIFigure);
            app.STEP13Label_2.Text = 'STEP 13';
            app.WARNINGLabel = uilabel(app.UIFigure);
            app.WARNINGLabel.Text = 'WARNING:';
            app.WILLSTOREDATAASFILESBUTLabel = uilabel(app.UIFigure);
            app.WILLSTOREDATAASFILESBUTLabel.Text = 'WILL STORE DATA AS FILES BUT ';
            app.WILLERASEALLDATAFROMLabel = uilabel(app.UIFigure);
            app.WILLERASEALLDATAFROMLabel.Text = 'WILL ERASE ALL DATA FROM ';
            app.TEMPORARYMEMORYLabel = uilabel(app.UIFigure);
            app.TEMPORARYMEMORYLabel.Text = 'TEMPORARY MEMORY.';
            app.SUBMITPEAKSButton = uibutton(app.UIFigure, 'push');
            app.SUBMITPEAKSButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITPEAKSButtonPushed, true);
            app.SUBMITPEAKSButton.Text = 'SUBMIT PEAKS';
            app.UPDATEFILEButton = uibutton(app.UIFigure, 'push');
            app.UPDATEFILEButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEFILEButtonPushed, true);
            app.UPDATEFILEButton.Text = 'UPDATE FILE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMAGING_ANALYSIS_V6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end